classdef Hill_function_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DropDown                      matlab.ui.control.DropDown
        UIAxes                        matlab.ui.control.UIAxes
        TabGroup                      matlab.ui.container.TabGroup
        Activator                     matlab.ui.container.Tab
        Kd_KnobA                      matlab.ui.control.Knob
        HalfconcentrationconstantKdKnobLabel_2  matlab.ui.control.Label
        HillCoefficientSliderLabel_2  matlab.ui.control.Label
        Hill_SliderA                  matlab.ui.control.Slider
        BasalExpressionSliderLabel_2  matlab.ui.control.Label
        Alpha_SliderA                 matlab.ui.control.Slider
        Repressor                     matlab.ui.container.Tab
        Kd_KnobR                      matlab.ui.control.Knob
        HalfconcentrationconstantKdKnobLabel_3  matlab.ui.control.Label
        HillCoefficientSliderLabel_3  matlab.ui.control.Label
        Hill_SliderR                  matlab.ui.control.Slider
        LeakinessLabel                matlab.ui.control.Label
        Alpha_SliderR                 matlab.ui.control.Slider
    end

    
    properties (Access = private)
        
    end
    
    methods (Access = private)
        
        function updateplot(app)
            
            % Define 
            A = logspace(-1,5,2000);
            Kd = app.Kd_KnobA.Value;
            n = app.Hill_SliderA.Value;
            b0 = app.Alpha_SliderA.Value;
            
            KdR = app.Kd_KnobR.Value;
            nR = app.Hill_SliderR.Value;
            b0R = app.Alpha_SliderR.Value;
            
            % Calculate plot 
            ACT = b0 + (1-b0).* A.^n./(Kd.^n + A.^n);
            REP = b0R + (1-b0R).* KdR.^nR./(KdR.^nR + A.^nR);
            
            if (app.TabGroup.SelectedTab == app.Repressor)
                plotline2 = semilogx(app.UIAxes, A, REP,'LineWidth',2);
                title(app.UIAxes, 'Hill Function (Repressor)');
                xlabel(app.UIAxes,'Number of molecules of Repressor');
            else
                plotline2 = semilogx(app.UIAxes, A, ACT,'LineWidth',2);
                title(app.UIAxes, 'Hill Function (Activator)');
                xlabel(app.UIAxes,'Number of molecules of Activator');
            end
            
            % Set line color
            plotline2.Color = app.DropDown.Value;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            % Plot 
            updateplot(app);
        end

        % Value changed function: DropDown
        function ComponentValueChanged(app, event)
            % Plot the polar function
            updateplot(app);
        end

        % Value changed function: Hill_SliderA
        function Hill_SliderValueChanged(app, event)
            value = app.Hill_SliderA.Value;
            updateplot(app);
            
        end

        % Callback function
        function LigandSliderValueChanged(app, event)
            
        end

        % Value changed function: Alpha_SliderA
        function AlphaSliderValueChanged(app, event)
            value = app.Alpha_SliderA.Value;
            updateplot(app);
        end

        % Callback function
        function Kd_KnobValueChanged(app, event)
            value = app.Kd_KnobA.Value;
            updateplot(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            updateplot(app);
        end

        % Value changed function: Kd_KnobA
        function Kd_KnobAValueChanged(app, event)
            value = app.Kd_KnobA.Value;
            updateplot(app);
        end

        % Value changed function: Kd_KnobR
        function Kd_KnobRValueChanged(app, event)
            value = app.Kd_KnobR.Value;
            updateplot(app);
        end

        % Value changed function: Hill_SliderR
        function Hill_SliderRValueChanged(app, event)
            value = app.Hill_SliderR.Value;
            updateplot(app);
        end

        % Value changed function: Alpha_SliderR
        function Alpha_SliderRValueChanged(app, event)
            value = app.Alpha_SliderR.Value;
            updateplot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Hill Function Plot';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Blue Line', 'Red Line', 'Green Line', 'Black Line'};
            app.DropDown.ItemsData = {'b', 'r', 'g', 'k'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.DropDown.Value = 'b';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Hill Function (Activator)')
            xlabel(app.UIAxes, {'Number of molecules of Activator'; ''})
            ylabel(app.UIAxes, 'Normalized Hill Function')
            app.UIAxes.XLim = [1 100000];
            app.UIAxes.YLim = [0 1.1];
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Activator = uitab(app.TabGroup);
            app.Activator.Title = 'Activator';
            app.Kd_KnobA = uiknob(app.Activator, 'continuous');
            app.Kd_KnobA.Limits = [0.1 2000];
            app.Kd_KnobA.ValueChangedFcn = createCallbackFcn(app, @Kd_KnobAValueChanged, true);
            app.Kd_KnobA.Value = 250;
            app.HalfconcentrationconstantKdKnobLabel_2 = uilabel(app.Activator);
            app.HalfconcentrationconstantKdKnobLabel_2.Text = 'Half-concentration constant (Khc)';
            app.HillCoefficientSliderLabel_2 = uilabel(app.Activator);
            app.HillCoefficientSliderLabel_2.Text = 'Hill Coefficient';
            app.Hill_SliderA = uislider(app.Activator);
            app.Hill_SliderA.Limits = [0.5 4];
            app.Hill_SliderA.ValueChangedFcn = createCallbackFcn(app, @Hill_SliderValueChanged, true);
            app.Hill_SliderA.Value = 2;
            app.BasalExpressionSliderLabel_2 = uilabel(app.Activator);
            app.BasalExpressionSliderLabel_2.Text = 'Basal Expression';
            app.Alpha_SliderA = uislider(app.Activator);
            app.Alpha_SliderA.Limits = [0 1];
            app.Alpha_SliderA.ValueChangedFcn = createCallbackFcn(app, @AlphaSliderValueChanged, true);
            app.Alpha_SliderA.Value = 0.1;
            app.Repressor = uitab(app.TabGroup);
            app.Repressor.Title = 'Repressor';
            app.Kd_KnobR = uiknob(app.Repressor, 'continuous');
            app.Kd_KnobR.Limits = [0.1 2000];
            app.Kd_KnobR.ValueChangedFcn = createCallbackFcn(app, @Kd_KnobRValueChanged, true);
            app.Kd_KnobR.Value = 250;
            app.HalfconcentrationconstantKdKnobLabel_3 = uilabel(app.Repressor);
            app.HalfconcentrationconstantKdKnobLabel_3.Text = 'Half-concentration constant (Khc)';
            app.HillCoefficientSliderLabel_3 = uilabel(app.Repressor);
            app.HillCoefficientSliderLabel_3.Text = 'Hill Coefficient';
            app.Hill_SliderR = uislider(app.Repressor);
            app.Hill_SliderR.Limits = [0.5 4];
            app.Hill_SliderR.ValueChangedFcn = createCallbackFcn(app, @Hill_SliderRValueChanged, true);
            app.Hill_SliderR.Value = 2;
            app.LeakinessLabel = uilabel(app.Repressor);
            app.LeakinessLabel.Text = 'Leakiness';
            app.Alpha_SliderR = uislider(app.Repressor);
            app.Alpha_SliderR.Limits = [0 1];
            app.Alpha_SliderR.ValueChangedFcn = createCallbackFcn(app, @Alpha_SliderRValueChanged, true);
            app.Alpha_SliderR.Value = 0.1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Hill_function_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end